classdef CauGo_AI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        tep_tin                        matlab.ui.container.Menu
        TomiMenu                       matlab.ui.container.Menu
        MtptinMenu                     matlab.ui.container.Menu
        LuMenu                         matlab.ui.container.Menu
        LuthnhMenu                     matlab.ui.container.Menu
        XutbocoMenu                    matlab.ui.container.Menu
        chuong_trinh_tinh_toan         matlab.ui.container.Menu
        TnhtonthitkcugMenu_2           matlab.ui.container.Menu
        hien_thi                       matlab.ui.container.Menu
        GiithiutiMenu                  matlab.ui.container.Menu
        PhmvipdngMenu                  matlab.ui.container.Menu
        NhmthchinMenu                  matlab.ui.container.Menu
        lien_ket                       matlab.ui.container.Menu
        CstnhtonbphncugMenu            matlab.ui.container.Menu
        LinktMenu                      matlab.ui.container.Menu
        MicrosoftWordMenu              matlab.ui.container.Menu
        MicrosoftExcelMenu             matlab.ui.container.Menu
        GmailMenu                      matlab.ui.container.Menu
        TrnhduytMenu                   matlab.ui.container.Menu
        TrgipMenu                      matlab.ui.container.Menu
        HngdnsdngMenu                  matlab.ui.container.Menu
        VdminhhoMenu                   matlab.ui.container.Menu
        VphnmmMenu                     matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        PushTool2                      matlab.ui.container.toolbar.PushTool
        PushTool3                      matlab.ui.container.toolbar.PushTool
        PushTool4                      matlab.ui.container.toolbar.PushTool
        PushTool6                      matlab.ui.container.toolbar.PushTool
        PushTool7                      matlab.ui.container.toolbar.PushTool
        PushTool8                      matlab.ui.container.toolbar.PushTool
        PushTool9                      matlab.ui.container.toolbar.PushTool
        PanelInputChanGia              matlab.ui.container.Panel
        DD_dXaDau                      matlab.ui.control.DropDown
        DD_dCotGia                     matlab.ui.control.DropDown
        DD_LoaiGoGiangCheo             matlab.ui.control.DropDown
        Lbl_ChieuCaoChanCau_11         matlab.ui.control.Label
        Lbl_ChieuCaoChanCau_10         matlab.ui.control.Label
        DD_LoaiGoDaKe                  matlab.ui.control.DropDown
        DD_m_cot                       matlab.ui.control.DropDown
        Lbl_ChieuCaoChanCau_5          matlab.ui.control.Label
        Lbl_DuongKinhXaDauGiaThiet     matlab.ui.control.Label
        Lbl_DuongKinhCotGiaGiaThiet    matlab.ui.control.Label
        EF_H_chan                      matlab.ui.control.NumericEditField
        Lbl_ChieuCaoChanCau            matlab.ui.control.Label
        BT_TinhChanGia                 matlab.ui.control.Button
        BT_TinhMoCau                   matlab.ui.control.Button
        Panel_KetQuaChanGia            matlab.ui.container.Panel
        EF_SL_giangcheo_chancoc        matlab.ui.control.NumericEditField
        EF_chieudai_giangcheo_chancoc  matlab.ui.control.NumericEditField
        EF_kt_giangcheo_chancoc        matlab.ui.control.EditField
        Lbl_kt_bv_27                   matlab.ui.control.Label
        EF_SL_giangcheo_changia        matlab.ui.control.NumericEditField
        EF_SL_coc                      matlab.ui.control.NumericEditField
        EF_l_coc                       matlab.ui.control.NumericEditField
        EF_kt_coc                      matlab.ui.control.EditField
        Lbl_kt_bv_26                   matlab.ui.control.Label
        EF_SL_xadau_changia            matlab.ui.control.NumericEditField
        EF_SL_dake_changia             matlab.ui.control.NumericEditField
        EF_SL_cotgia_changia           matlab.ui.control.NumericEditField
        Lbl_SL_changia                 matlab.ui.control.Label
        EF_chieudai_giangcheo          matlab.ui.control.NumericEditField
        EF_kt_giangcheo                matlab.ui.control.EditField
        Lbl_kt_bv_19                   matlab.ui.control.Label
        EF_chieudai_dake               matlab.ui.control.NumericEditField
        EF_kt_dake                     matlab.ui.control.EditField
        Lbl_kt_bv_18                   matlab.ui.control.Label
        EF_l_cotgia                    matlab.ui.control.NumericEditField
        EF_kt_cotgia                   matlab.ui.control.EditField
        Lbl_kt_bv_17                   matlab.ui.control.Label
        Lbl_kt_bv_14                   matlab.ui.control.Label
        Lbl_kt_bv_13                   matlab.ui.control.Label
        Lbl_kt_bv_12                   matlab.ui.control.Label
        EF_chieudai_xadau              matlab.ui.control.NumericEditField
        EF_kt_xadau                    matlab.ui.control.EditField
        Panel_KetQuaMoCau              matlab.ui.container.Panel
        EF_SL_vanchandat               matlab.ui.control.NumericEditField
        EF_chieudai_vanchandat         matlab.ui.control.NumericEditField
        EF_kt_vanchandat               matlab.ui.control.EditField
        Lbl_kt_bv_35                   matlab.ui.control.Label
        EF_SL_cocbitdaudam             matlab.ui.control.NumericEditField
        EF_SL_cocghim                  matlab.ui.control.NumericEditField
        EF_l_cocghim                   matlab.ui.control.NumericEditField
        EF_kt_cocghim                  matlab.ui.control.EditField
        Lbl_kt_bv_34                   matlab.ui.control.Label
        EF_SL_damo                     matlab.ui.control.NumericEditField
        EF_SL_cochuongdanxe            matlab.ui.control.NumericEditField
        EF_SL_dake                     matlab.ui.control.NumericEditField
        Lbl_SL_changia_3               matlab.ui.control.Label
        EF_chieudai_cocbitdaudam       matlab.ui.control.NumericEditField
        EF_kt_cocbitdaudam             matlab.ui.control.EditField
        Lbl_kt_bv_33                   matlab.ui.control.Label
        EF_chieudai_cochuongdanxe      matlab.ui.control.NumericEditField
        EF_kt_cochuongdanxe            matlab.ui.control.EditField
        Lbl_kt_bv_32                   matlab.ui.control.Label
        EF_l_dakemo                    matlab.ui.control.NumericEditField
        EF_kt_dekemo                   matlab.ui.control.EditField
        Lbl_kt_bv_31                   matlab.ui.control.Label
        Lbl_kt_bv_30                   matlab.ui.control.Label
        Lbl_kt_bv_29                   matlab.ui.control.Label
        Lbl_kt_bv_28                   matlab.ui.control.Label
        EF_chieudai_damo               matlab.ui.control.NumericEditField
        EF_kt_damo                     matlab.ui.control.EditField
        EF_SL_xade_changia             matlab.ui.control.NumericEditField
        Lbl_LanCan                     matlab.ui.control.Label
        EF_LanCan                      matlab.ui.control.NumericEditField
        Lbl_LeBoHanh                   matlab.ui.control.Label
        EF_LeBoHanh                    matlab.ui.control.NumericEditField
        EF_chieudai_xade               matlab.ui.control.NumericEditField
        EF_kt_xade                     matlab.ui.control.EditField
        Lbl_kt_bv_16                   matlab.ui.control.Label
        Panel_KetQuaThietKe            matlab.ui.container.Panel
        Panel_KetQuaMatCau             matlab.ui.container.Panel
        Lbl_Lvln_2                     matlab.ui.control.Label
        EF_slg_vanvet                  matlab.ui.control.NumericEditField
        EF_slg_bovia                   matlab.ui.control.NumericEditField
        EF_slg_vln                     matlab.ui.control.NumericEditField
        EF_Ldamcau_trongbo_2           matlab.ui.control.NumericEditField
        EF_Ldamcau_ngoaikhoi_2         matlab.ui.control.NumericEditField
        EF_lbv_bovia_2                 matlab.ui.control.NumericEditField
        Lbl_SoLopVanVet_2              matlab.ui.control.Label
        EF_Lt                          matlab.ui.control.NumericEditField
        EF_kt_damcau_2                 matlab.ui.control.EditField
        Lbl_BoVia_3                    matlab.ui.control.Label
        Lbl_SigmaAllow_2               matlab.ui.control.Label
        Lbl_kt_vv                      matlab.ui.control.Label
        Lbl_BoVia                      matlab.ui.control.Label
        EF_lbv_bovia                   matlab.ui.control.NumericEditField
        Lbl_VLN                        matlab.ui.control.Label
        EF_lvln_vln                    matlab.ui.control.NumericEditField
        Lbl_Lvln                       matlab.ui.control.Label
        EF_kt_vanvet                   matlab.ui.control.EditField
        EF_kt_bovia                    matlab.ui.control.EditField
        EF_kt_vln                      matlab.ui.control.EditField
        Lbl_BoVia_2                    matlab.ui.control.Label
        EF_kt_damcau                   matlab.ui.control.EditField
        EF_Ldamcau_trongbo             matlab.ui.control.NumericEditField
        EF_Ldamcau_ngoaikhoi           matlab.ui.control.NumericEditField
        PanelNhapSoLieu                matlab.ui.container.Panel
        PanelInputNhipCau              matlab.ui.container.Panel
        DD_NhomGo_VanLatNgang          matlab.ui.control.DropDown
        Lbl_NhomGo_VanLatNgang         matlab.ui.control.Label
        DD_LoaiGoVanLatNgang           matlab.ui.control.DropDown
        Lbl_LoaiGoBoVia_2              matlab.ui.control.Label
        DD_LoaiGoBoVia                 matlab.ui.control.DropDown
        Lbl_LoaiGoBoVia                matlab.ui.control.Label
        Lbl_LoaiGoVanVet               matlab.ui.control.Label
        DD_LoaiGoVanVet                matlab.ui.control.DropDown
        Lbl_SoLopVanVet                matlab.ui.control.Label
        DD_SoLopVanVet                 matlab.ui.control.DropDown
        PanelInputMoCau                matlab.ui.container.Panel
        DD_DoCungNenDat                matlab.ui.control.DropDown
        Lbl_LoaiGoBoVia_5              matlab.ui.control.Label
        DD_LoaiGoDaKeMoCau             matlab.ui.control.DropDown
        Lbl_LoaiGoBoVia_4              matlab.ui.control.Label
        DD_LoaiGoBoVia_2               matlab.ui.control.DropDown
        Lbl_LoaiGoBoVia_3              matlab.ui.control.Label
        PanelChonKetCau                matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        ButtonNhipCau                  matlab.ui.control.RadioButton
        ButtonMoCau                    matlab.ui.control.RadioButton
        ButtonChanGia                  matlab.ui.control.RadioButton
        PanelHinhVeMinhHoa             matlab.ui.container.Panel
        PanelThongSoChung              matlab.ui.container.Panel
        DD_SoLopNhipCau                matlab.ui.control.DropDown
        DD_n                           matlab.ui.control.DropDown
        Lbl_SoDam                      matlab.ui.control.Label
        Lbl_ChieuDaiNhipTinhToan       matlab.ui.control.Label
        EF_L                           matlab.ui.control.NumericEditField
        Lbl_ChieuDaiCau                matlab.ui.control.Label
        EF_B                           matlab.ui.control.NumericEditField
        Lbl_ChieuRongLongCau           matlab.ui.control.Label
        DD_H                           matlab.ui.control.DropDown
        Lbl_TaiTrongXeBanhLop          matlab.ui.control.Label
        DD_X                           matlab.ui.control.DropDown
        Lbl_TaiTrongXeBanhXich         matlab.ui.control.Label
        PanelNut                       matlab.ui.container.Panel
        BT_BatDauTinh                  matlab.ui.control.Button
        BT_ThongKe                     matlab.ui.control.Button
        BT_KiemTra                     matlab.ui.control.Button
        BT_KetThuc                     matlab.ui.control.Button
        BT_XuatDuLieu                  matlab.ui.control.Button
        tren                           matlab.ui.container.Panel
        KhauHieu                       matlab.ui.control.Label
    end

    properties (Access = private)
        DS struct = struct()
    end


    methods (Access = private)

        function DS = ui2ds_VanVet(app)

            DS = app.DS;

            % ===== Thông số chung =====
            DS.ThongSoChung.H = app.DD_H.Value;

            % ===== Đầu vào riêng ván vệt =====
            loaiGo = app.DD_LoaiGoVanVet.Value;
            if strlength(strtrim(string(loaiGo))) == 0
                loaiGo = "Gỗ xẻ";
            end

            DS.DauVao.LoaiGoVanVet = loaiGo;

        end

        function run_vanvet(app)

            % UI -> DS
            app.DS = ui2ds_VanVet(app);

            % Core
            app.DS = core.nhipcau.solve_vanvet(app.DS);

            % Report (tạm thời: format chuỗi hiển thị = kt)
            kt = app.DS.KetQua.NhipCau.VanVet.kt;

            % UI
            app.EF_kt_vanvet.Value = kt;

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% TÊN CHƯƠNG TRÌNH
            app.UIFigure.Name = ...
                'CHƯƠNG TRÌNH TÍNH TOÁN, THIẾT KẾ BỘ PHẬN CẦU GỖ';


            app.DS = struct();
            app.DS.ThongSoChung = struct();
            app.DS.DauVao = struct();
        end

        % Callback function: PushTool9
        function PushTool_DeleteApp(app, event)
            ok = askExitPopup(app, ["Bạn có muốn thoát chương trình không?" ; "Mọi dữ liệu chưa lưu sẽ bị mất."]);
            if ok
                delete(app);
            end
        end

        % Value changed function: DD_NhomGo_VanLatNgang
        function DD_NhomGo_VanLatNgangValueChanged(app, event)

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            updateMainModeUI(app);
        end

        % Value changed function: EF_H_chan
        function EF_H_chanValueChanged2(app, event)
            % Validate trước khi tính
            if ~validateChanGia(app)
                return;
            end
            % Đồng bộ H từ UI -> DS
            syncUI2DS_ChanGia(app);

            % Tra d_min theo X – Lt – H
            tra_dmin_theo_X_Lt_H(app);

            % Sinh list d_base + init lựa chọn
            gen_d_list_base_and_init_choice(app);

            % Fill dropdown (dCotGia, dXaDau)
            fillDD_d_CotGia_XaDau(app);
        end

        % Button pushed function: BT_TinhChanGia
        function BT_TinhChanGiaButtonPushed(app, event)

            syncUI2DS_ChanGia(app);
            traQS_CotGia(app);
            % Tính chiều dài chân giá
            app = calc_lXaDau_ChanGia(app);
            app.EF_chieudai_xadau.Value = app.DS.ChanGia.lXaDau;
            app.EF_chieudai_xade.Value = app.DS.ChanGia.lXaDau;
            % Tính số lượng xà đầu, xà đế
            app.EF_SL_xadau_changia.Value = 1;
            app.EF_SL_xade_changia.Value = 1;
            % Tính số lượng cột giá
            app.EF_SL_cotgia_changia.Value = double(app.DS.ChanGia.SL_cotGia);
            app.EF_SL_coc.Value = double(app.DS.ChanGia.SL_cotGia);
            % Tính số lượng đà kê cả chân
            app = calc_DaKe_ChanGia(app);
            app.EF_SL_dake_changia.Value = app.DS.ChanGia.SL_dake_caChan;
            % Tính chiều dài mỗi đà kê chân giá
            app = calc_lDaKe_ChanGia(app);
            app.EF_chieudai_dake.Value = app.DS.ChanGia.lDaKe;
            % Tính kích thước đà kê
            app = calc_ktDaKe_ChanGia(app);
            app.EF_kt_dake.Value = app.DS.ChanGia.kt_dake;
            % Tính số lượng và kích thước giằng chéo
            app = calc_GiangCheo_ChanGia(app);
            app.EF_SL_giangcheo_changia.Value = app.DS.ChanGia.SL_giangCheo;
            app.EF_kt_giangcheo.Value         = app.DS.ChanGia.kt_giangCheo;

            %% Phần tính toán xà đầu, xà đế, cột giá
            calc_g_tinhTai_cotgia(app);
            calc_At_cotgia(app);
            calc_Ah_cotgia(app);
            calc_K_phanBoNgang_CotGia(app);
            calc_Amax_cotgia(app);


            calc_hXaDau_ChanGia(app);
            calc_l0_coc_ChanGia(app); % CỘT
            calc_l_coc(app); % CỌC
            calc_rmin_lambda_CotGia(app);
            calc_phi_CotGia(app);
            calc_Rn_CotGia(app);
            outUI_CotGia(app);
            %% Phần tính xà đầu, xà đế

            calc_A1_XaDau(app);
            calc_A2_XaDau(app);
            set_K_phanBoNgang_XaDau(app);
            calc_Ac_XaDau(app);
            calc_Fem_XaDau(app);
            calc_sigma_em_XaDau(app);
            outUI_XaDau(app);


            % Tính chiều dài cột giá
            calc_lcg_CotGia(app);          % ra lcg_cm + EF_l_cotgia
            % Tính chiều dài giằng chéo chân giá
            calc_lGiangCheo_ChanGia(app);
            app.EF_chieudai_giangcheo.Value = app.DS.ChanGia.l_giangcheo_cm;
            %% Tính chân cọc

            calc_l_giangcheo_chancoc(app);
            app.EF_SL_giangcheo_chancoc.Value = app.DS.ChanGia.SL_giangCheo;
            app.EF_kt_giangcheo_chancoc.Value = app.DS.ChanGia.kt_giangCheo;


            % In ra command window để check
            debugChanGia(app)

        end

        % Button pushed function: BT_KetThuc
        function BT_KetThucButtonPushed(app, event)
            ok = askExitPopup(app, ["Bạn có muốn thoát chương trình không?" ; "Mọi dữ liệu chưa lưu sẽ bị mất."]);
            if ok
                delete(app);
            end
        end

        % Button pushed function: BT_BatDauTinh
        function BT_BatDauTinhButtonPushed(app, event)
            run_vanvet(app);

        end

        % Value changed function: DD_dCotGia
        function DD_dCotGiaValueChanged(app, event)
            d = sscanf(string(app.DD_dCotGia.Value), "%f");
            app.DS.ChanGia.d_chon_CotGia_cm = d;
        end

        % Value changed function: DD_dXaDau
        function DD_dXaDauValueChanged(app, event)
            d = sscanf(string(app.DD_dXaDau.Value), "%f");
            app.DS.XaDau.d_chon_XaDau_cm = d;

        end

        % Button pushed function: BT_TinhMoCau
        function BT_TinhMoCauButtonPushed(app, event)

            calc_DaMo(app);

            calc_DaKe(app);

            calc_CocGhim(app);

            calc_l_cocghim(app);

            calc_CocBitDauDam(app);

            calc_VanChanDat(app);

            calc_CocHuongDanXe(app);

        end

        % Button pushed function: BT_KiemTra
        function BT_KiemTraButtonPushed(app, event)
            disp("=== DS.KetQua.NhipCau.VanVet ===");

            if ~isfield(app.DS,'KetQua')
                disp("DS chưa có field KetQua");
                return;
            end

            if ~isfield(app.DS.KetQua,'NhipCau')
                disp("DS.KetQua chưa có field NhipCau");
                return;
            end

            if ~isfield(app.DS.KetQua.NhipCau,'VanVet')
                disp("DS.KetQua.NhipCau chưa có field VanVet");
                return;
            end

            disp(app.DS.KetQua.NhipCau.VanVet);

        end

        % Button pushed function: BT_ThongKe
        function BT_ThongKeButtonPushed(app, event)
            showWarningPopup(app, [ ...
                "Chức năng chưa phát triển!" ...
                ]);
        end

        % Button pushed function: BT_XuatDuLieu
        function BT_XuatDuLieuButtonPushed(app, event)


    exportFolder = "C:\Users\ADM\CauGo_Project\+xuatdulieu";   % thư mục cố định bạn yêu cầu

    % 1) Đặt tên file: DuLieuCauGo_YYYYMMDD_HHMMSS_LanNN.xlsx
    lan = numel(dir(fullfile(exportFolder, "DuLieuCauGo_*.xlsx"))) + 1;
    ts = datetime('now','Format','yyyyMMdd_HHmmss');
    fileName = sprintf("DuLieuCauGo_%s_Lan%02d.xlsx", char(ts), lan);
    fullpath = fullfile(exportFolder, fileName);

    % 2) Report -> IO
    sheets = report.make_export_vanvet(app.DS);
    io.export_to_excel(fullpath, sheets);     % fullpath là đường dẫn đầy đủ

    % 3) Mở file ngay
    winopen(char(fullpath));

    uialert(app.UIFigure, "Đã xuất và mở: " + string(fileName), "OK");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.tep_tin = uimenu(app.UIFigure);
            app.tep_tin.Text = 'Tệp tin';
            app.TomiMenu = uimenu(app.tep_tin);
            app.TomiMenu.Separator = 'on';
            app.TomiMenu.Accelerator = 'N';
            app.TomiMenu.Text = '1. Tạo mới';
            app.MtptinMenu = uimenu(app.tep_tin);
            app.MtptinMenu.Separator = 'on';
            app.MtptinMenu.Accelerator = 'O';
            app.MtptinMenu.Text = '2. Mở tập tin';
            app.LuMenu = uimenu(app.tep_tin);
            app.LuMenu.Separator = 'on';
            app.LuMenu.Accelerator = 'S';
            app.LuMenu.Text = '3. Lưu';
            app.LuthnhMenu = uimenu(app.tep_tin);
            app.LuthnhMenu.Separator = 'on';
            app.LuthnhMenu.Accelerator = 'A';
            app.LuthnhMenu.Text = '4. Lưu thành...';
            app.XutbocoMenu = uimenu(app.tep_tin);
            app.XutbocoMenu.Separator = 'on';
            app.XutbocoMenu.Accelerator = 'E';
            app.XutbocoMenu.Text = '5. Xuất báo cáo';
            app.chuong_trinh_tinh_toan = uimenu(app.UIFigure);
            app.chuong_trinh_tinh_toan.Text = 'Chương trình tính toán';
            app.TnhtonthitkcugMenu_2 = uimenu(app.chuong_trinh_tinh_toan);
            app.TnhtonthitkcugMenu_2.Separator = 'on';
            app.TnhtonthitkcugMenu_2.Accelerator = 'F';
            app.TnhtonthitkcugMenu_2.Text = '1. Tính toán - thiết kế cầu gỗ';
            app.hien_thi = uimenu(app.UIFigure);
            app.hien_thi.Text = 'Giới thiệu đề tài';
            app.GiithiutiMenu = uimenu(app.hien_thi);
            app.GiithiutiMenu.Separator = 'on';
            app.GiithiutiMenu.Text = '1. Giới thiệu đề tài';
            app.PhmvipdngMenu = uimenu(app.hien_thi);
            app.PhmvipdngMenu.Separator = 'on';
            app.PhmvipdngMenu.Text = '2. Phạm vi áp dụng';
            app.NhmthchinMenu = uimenu(app.hien_thi);
            app.NhmthchinMenu.Separator = 'on';
            app.NhmthchinMenu.Text = '3. Nhóm thực hiện';
            app.lien_ket = uimenu(app.UIFigure);
            app.lien_ket.Text = 'Cơ sở tính toán';
            app.CstnhtonbphncugMenu = uimenu(app.lien_ket);
            app.CstnhtonbphncugMenu.Separator = 'on';
            app.CstnhtonbphncugMenu.Text = '1. Cơ sở tính toán bộ phận cầu gỗ';
            app.LinktMenu = uimenu(app.UIFigure);
            app.LinktMenu.Text = 'Liên kết';
            app.MicrosoftWordMenu = uimenu(app.LinktMenu);
            app.MicrosoftWordMenu.Separator = 'on';
            app.MicrosoftWordMenu.Text = '1. Microsoft Word';
            app.MicrosoftExcelMenu = uimenu(app.LinktMenu);
            app.MicrosoftExcelMenu.Separator = 'on';
            app.MicrosoftExcelMenu.Text = '2. Microsoft Excel';
            app.GmailMenu = uimenu(app.LinktMenu);
            app.GmailMenu.Separator = 'on';
            app.GmailMenu.Text = '3. Gmail';
            app.TrnhduytMenu = uimenu(app.LinktMenu);
            app.TrnhduytMenu.Separator = 'on';
            app.TrnhduytMenu.Text = '4. Trình duyệt';
            app.TrgipMenu = uimenu(app.UIFigure);
            app.TrgipMenu.Text = 'Trợ giúp';
            app.HngdnsdngMenu = uimenu(app.TrgipMenu);
            app.HngdnsdngMenu.Separator = 'on';
            app.HngdnsdngMenu.Text = '1. Hướng dẫn sử dụng';
            app.VdminhhoMenu = uimenu(app.TrgipMenu);
            app.VdminhhoMenu.Separator = 'on';
            app.VdminhhoMenu.Text = '2. Ví dụ minh hoạ';
            app.VphnmmMenu = uimenu(app.TrgipMenu);
            app.VphnmmMenu.Separator = 'on';
            app.VphnmmMenu.Text = '3. Về phần mềm';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Chương trình mới (Ctrl + N)'};
            app.PushTool.Icon = fullfile(pathToMLAPP, 'cac_chuc_nang', 'new.png');
            app.PushTool.Separator = 'on';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Mở tệp lưu trữ (Ctrl + O)'};
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'cac_chuc_nang', 'open.png');
            app.PushTool2.Separator = 'on';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Lưu kết quả (Ctrl + S)'};
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'cac_chuc_nang', 'save.png');
            app.PushTool3.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Chương trình tính toán (Ctrl + F)'};
            app.PushTool4.Icon = fullfile(pathToMLAPP, 'cac_chuc_nang', 'tinh_toan.png');
            app.PushTool4.Separator = 'on';
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.Tooltip = {'Xuất kết quả sang Excel (Ctrl + E)'};
            app.PushTool6.Icon = fullfile(pathToMLAPP, 'cac_chuc_nang', 'excel.png');
            app.PushTool6.Separator = 'on';
            app.PushTool7 = uipushtool(app.Toolbar);
            app.PushTool7.Tooltip = {'In báo cáo (Ctrl + P)'};
            app.PushTool7.Icon = fullfile(pathToMLAPP, 'cac_chuc_nang', 'print.png');
            app.PushTool7.Separator = 'on';
            app.PushTool8 = uipushtool(app.Toolbar);
            app.PushTool8.Tooltip = {'Hướng dẫn (Ctrl + H)'};
            app.PushTool8.Icon = fullfile(pathToMLAPP, 'cac_chuc_nang', 'question.png');
            app.PushTool8.Separator = 'on';
            app.PushTool9 = uipushtool(app.Toolbar);
            app.PushTool9.Tooltip = {'Thoát (Ctrl + Q)'};
            app.PushTool9.ClickedCallback = createCallbackFcn(app, @PushTool_DeleteApp, true);
            app.PushTool9.Icon = fullfile(pathToMLAPP, 'cac_chuc_nang', 'quit.png');
            app.PushTool9.Separator = 'on';
            app.tren = uipanel(app.UIFigure);
            app.KhauHieu = uilabel(app.tren);
            app.KhauHieu.Text = 'CHƯƠNG TRÌNH TÍNH TOÁN, THIẾT KẾ CÁC BỘ PHẬN CẦU GỖ';
            app.PanelNut = uipanel(app.UIFigure);
            app.BT_XuatDuLieu = uibutton(app.PanelNut, 'push');
            app.BT_XuatDuLieu.ButtonPushedFcn = createCallbackFcn(app, @BT_XuatDuLieuButtonPushed, true);
            app.BT_XuatDuLieu.Text = 'XUẤT DỮ LIỆU';
            app.BT_KetThuc = uibutton(app.PanelNut, 'push');
            app.BT_KetThuc.ButtonPushedFcn = createCallbackFcn(app, @BT_KetThucButtonPushed, true);
            app.BT_KetThuc.Text = 'KẾT THÚC';
            app.BT_KiemTra = uibutton(app.PanelNut, 'push');
            app.BT_KiemTra.ButtonPushedFcn = createCallbackFcn(app, @BT_KiemTraButtonPushed, true);
            app.BT_KiemTra.Text = 'KIỂM TRA';
            app.BT_ThongKe = uibutton(app.PanelNut, 'push');
            app.BT_ThongKe.ButtonPushedFcn = createCallbackFcn(app, @BT_ThongKeButtonPushed, true);
            app.BT_ThongKe.Text = 'THỐNG KÊ';
            app.BT_BatDauTinh = uibutton(app.PanelNut, 'push');
            app.BT_BatDauTinh.ButtonPushedFcn = createCallbackFcn(app, @BT_BatDauTinhButtonPushed, true);
            app.BT_BatDauTinh.Text = 'TÍNH NHỊP CẦU';
            app.PanelThongSoChung = uipanel(app.UIFigure);
            app.PanelThongSoChung.Title = 'Thông số chung về cầu gỗ:';
            app.Lbl_TaiTrongXeBanhXich = uilabel(app.PanelThongSoChung);
            app.Lbl_TaiTrongXeBanhXich.Text = 'X - Tải trọng xe bánh xích';
            app.DD_X = uidropdown(app.PanelThongSoChung);
            app.DD_X.Items = {'X20', 'X40', 'X60'};
            app.DD_X.Value = 'X20';
            app.Lbl_TaiTrongXeBanhLop = uilabel(app.PanelThongSoChung);
            app.Lbl_TaiTrongXeBanhLop.Text = 'H - Tải trọng xe bánh lốp';
            app.DD_H = uidropdown(app.PanelThongSoChung);
            app.DD_H.Items = {'H8', 'H10', 'H13', 'H20', 'H30'};
            app.DD_H.Value = 'H8';
            app.Lbl_ChieuRongLongCau = uilabel(app.PanelThongSoChung);
            app.Lbl_ChieuRongLongCau.Text = 'B – Chiều rộng lòng cầu (m)';
            app.EF_B = uieditfield(app.PanelThongSoChung, 'numeric');
            app.EF_B.Limits = [0 Inf];
            app.EF_B.AllowEmpty = 'on';
            app.EF_B.Value = 4.2;
            app.Lbl_ChieuDaiCau = uilabel(app.PanelThongSoChung);
            app.Lbl_ChieuDaiCau.Text = 'L - Chiều dài cầu (m)';
            app.EF_L = uieditfield(app.PanelThongSoChung, 'numeric');
            app.EF_L.Limits = [0 Inf];
            app.EF_L.AllowEmpty = 'on';
            app.EF_L.Value = 12.5;
            app.Lbl_ChieuDaiNhipTinhToan = uilabel(app.PanelThongSoChung);
            app.Lbl_ChieuDaiNhipTinhToan.Text = 'Lt - Chiều dài nhịp tính toán (m)';
            app.Lbl_SoDam = uilabel(app.PanelThongSoChung);
            app.Lbl_SoDam.Text = 'n - Số dầm trong nhịp';
            app.DD_n = uidropdown(app.PanelThongSoChung);
            app.DD_n.Items = {'6', '7', '8', '9', '10', '11', '12'};
            app.DD_n.Value = '6';
            app.DD_SoLopNhipCau = uidropdown(app.PanelThongSoChung);
            app.DD_SoLopNhipCau.Items = {'1 nhịp', '2 nhịp', '3 nhịp', '4 nhịp', '5  nhịp', '6 nhịp', '7 nhịp', '8 nhịp', '..........'};
            app.DD_SoLopNhipCau.Value = '1 nhịp';
            app.PanelHinhVeMinhHoa = uipanel(app.UIFigure);
            app.PanelHinhVeMinhHoa.Title = 'Hình vẽ minh hoạ';
            app.PanelChonKetCau = uipanel(app.UIFigure);
            app.PanelChonKetCau.Title = 'Chọn kết cấu';
            app.ButtonGroup = uibuttongroup(app.PanelChonKetCau);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonChanGia = uiradiobutton(app.ButtonGroup);
            app.ButtonChanGia.Text = 'CHÂN CẦU';
            app.ButtonMoCau = uiradiobutton(app.ButtonGroup);
            app.ButtonMoCau.Text = 'MỐ CẦU';
            app.ButtonNhipCau = uiradiobutton(app.ButtonGroup);
            app.ButtonNhipCau.Text = 'NHỊP CẦU';
            app.ButtonNhipCau.Value = true;
            app.PanelNhapSoLieu = uipanel(app.UIFigure);
            app.PanelNhapSoLieu.Title = 'Thông số đầu vào';
            app.PanelInputMoCau = uipanel(app.PanelNhapSoLieu);
            app.Lbl_LoaiGoBoVia_3 = uilabel(app.PanelInputMoCau);
            app.Lbl_LoaiGoBoVia_3.Text = '1. Loại gỗ đà mố';
            app.DD_LoaiGoBoVia_2 = uidropdown(app.PanelInputMoCau);
            app.DD_LoaiGoBoVia_2.Items = {'Gỗ tròn'};
            app.DD_LoaiGoBoVia_2.Value = 'Gỗ tròn';
            app.Lbl_LoaiGoBoVia_4 = uilabel(app.PanelInputMoCau);
            app.Lbl_LoaiGoBoVia_4.Text = '2. Loại gỗ đà kê';
            app.DD_LoaiGoDaKeMoCau = uidropdown(app.PanelInputMoCau);
            app.DD_LoaiGoDaKeMoCau.Items = {'', 'Gỗ xẻ'};
            app.DD_LoaiGoDaKeMoCau.Value = 'Gỗ xẻ';
            app.Lbl_LoaiGoBoVia_5 = uilabel(app.PanelInputMoCau);
            app.Lbl_LoaiGoBoVia_5.Text = '3. Dộ cứng nền đất';
            app.DD_DoCungNenDat = uidropdown(app.PanelInputMoCau);
            app.DD_DoCungNenDat.Items = {'Đất cứng', 'Đất mềm'};
            app.DD_DoCungNenDat.Value = 'Đất cứng';
            app.PanelInputNhipCau = uipanel(app.PanelNhapSoLieu);
            app.DD_SoLopVanVet = uidropdown(app.PanelInputNhipCau);
            app.DD_SoLopVanVet.Items = {'0 lớp', '1 lớp', '2 lớp'};
            app.DD_SoLopVanVet.Value = '1 lớp';
            app.Lbl_SoLopVanVet = uilabel(app.PanelInputNhipCau);
            app.Lbl_SoLopVanVet.Text = '4. Số lớp ván vệt';
            app.DD_LoaiGoVanVet = uidropdown(app.PanelInputNhipCau);
            app.DD_LoaiGoVanVet.Items = {'Gỗ tròn', 'Gỗ xẻ'};
            app.DD_LoaiGoVanVet.Value = 'Gỗ xẻ';
            app.Lbl_LoaiGoVanVet = uilabel(app.PanelInputNhipCau);
            app.Lbl_LoaiGoVanVet.Text = '3. Loại gỗ ván vệt';
            app.Lbl_LoaiGoBoVia = uilabel(app.PanelInputNhipCau);
            app.Lbl_LoaiGoBoVia.Text = '1. Loại gỗ bó vỉa';
            app.DD_LoaiGoBoVia = uidropdown(app.PanelInputNhipCau);
            app.DD_LoaiGoBoVia.Items = {'Gỗ tròn', 'Gỗ xẻ'};
            app.DD_LoaiGoBoVia.Value = 'Gỗ xẻ';
            app.Lbl_LoaiGoBoVia_2 = uilabel(app.PanelInputNhipCau);
            app.Lbl_LoaiGoBoVia_2.Text = '2. Loại gỗ ván lát ngang';
            app.DD_LoaiGoVanLatNgang = uidropdown(app.PanelInputNhipCau);
            app.DD_LoaiGoVanLatNgang.Items = {'Gỗ tròn', 'Gỗ xẻ'};
            app.DD_LoaiGoVanLatNgang.Value = 'Gỗ xẻ';
            app.Lbl_NhomGo_VanLatNgang = uilabel(app.PanelInputNhipCau);
            app.Lbl_NhomGo_VanLatNgang.Text = '5. Nhóm gỗ thiết kế';
            app.DD_NhomGo_VanLatNgang = uidropdown(app.PanelInputNhipCau);
            app.DD_NhomGo_VanLatNgang.Items = {'Chọn nhóm gỗ', 'Nhóm I: Gỗ cứng (lim, táu, sến, nghiến)', 'Nhóm II: Gỗ cứng trung bình (dầu, sao, chò, dổi)', 'Nhóm III: Gỗ trung bình – phổ biến (keo, chàm, xoan, mỡ…)', 'Nhóm IV: Gỗ nhẹ – kết cấu phụ (tre, nứa, bồ đề…)'};
            app.DD_NhomGo_VanLatNgang.ValueChangedFcn = createCallbackFcn(app, @DD_NhomGo_VanLatNgangValueChanged, true);
            app.DD_NhomGo_VanLatNgang.Value = 'Chọn nhóm gỗ';
            app.Panel_KetQuaThietKe = uipanel(app.UIFigure);
            app.Panel_KetQuaThietKe.Title = 'Kết quả thiết kế';
            app.Panel_KetQuaMatCau = uipanel(app.Panel_KetQuaThietKe);
            app.EF_Ldamcau_ngoaikhoi = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_Ldamcau_ngoaikhoi.AllowEmpty = 'on';
            app.EF_Ldamcau_ngoaikhoi.Editable = 'off';
            app.EF_Ldamcau_ngoaikhoi.Value = [];
            app.EF_Ldamcau_trongbo = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_Ldamcau_trongbo.AllowEmpty = 'on';
            app.EF_Ldamcau_trongbo.Editable = 'off';
            app.EF_Ldamcau_trongbo.Value = [];
            app.EF_kt_damcau = uieditfield(app.Panel_KetQuaMatCau, 'text');
            app.EF_kt_damcau.Editable = 'off';
            app.Lbl_BoVia_2 = uilabel(app.Panel_KetQuaMatCau);
            app.Lbl_BoVia_2.Text = 'Dầm trong bờ';
            app.EF_kt_vln = uieditfield(app.Panel_KetQuaMatCau, 'text');
            app.EF_kt_vln.Editable = 'off';
            app.EF_kt_bovia = uieditfield(app.Panel_KetQuaMatCau, 'text');
            app.EF_kt_bovia.Editable = 'off';
            app.EF_kt_vanvet = uieditfield(app.Panel_KetQuaMatCau, 'text');
            app.EF_kt_vanvet.Editable = 'off';
            app.Lbl_Lvln = uilabel(app.Panel_KetQuaMatCau);
            app.Lbl_Lvln.Text = 'Chiều dài (cm)';
            app.EF_lvln_vln = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_lvln_vln.AllowEmpty = 'on';
            app.EF_lvln_vln.Editable = 'off';
            app.EF_lvln_vln.Value = [];
            app.Lbl_VLN = uilabel(app.Panel_KetQuaMatCau);
            app.Lbl_VLN.Text = 'Ván lát ngang';
            app.EF_lbv_bovia = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_lbv_bovia.AllowEmpty = 'on';
            app.EF_lbv_bovia.Editable = 'off';
            app.EF_lbv_bovia.Value = [];
            app.Lbl_BoVia = uilabel(app.Panel_KetQuaMatCau);
            app.Lbl_BoVia.Text = 'Bó vỉa';
            app.Lbl_kt_vv = uilabel(app.Panel_KetQuaMatCau);
            app.Lbl_kt_vv.Text = 'Kích thước (cm)';
            app.Lbl_SigmaAllow_2 = uilabel(app.Panel_KetQuaMatCau);
            app.Lbl_SigmaAllow_2.Text = 'Ván vệt';
            app.Lbl_BoVia_3 = uilabel(app.Panel_KetQuaMatCau);
            app.Lbl_BoVia_3.Text = 'Dầm ngoài khơi';
            app.EF_kt_damcau_2 = uieditfield(app.Panel_KetQuaMatCau, 'text');
            app.EF_kt_damcau_2.Editable = 'off';
            app.EF_Lt = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_Lt.Limits = [0 Inf];
            app.EF_Lt.AllowEmpty = 'on';
            app.EF_Lt.Editable = 'off';
            app.EF_Lt.Value = [];
            app.Lbl_SoLopVanVet_2 = uilabel(app.Panel_KetQuaMatCau);
            app.Lbl_SoLopVanVet_2.Text = 'Chiều dài nhịp tính toán Lt (m)';
            app.EF_lbv_bovia_2 = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_lbv_bovia_2.AllowEmpty = 'on';
            app.EF_lbv_bovia_2.Editable = 'off';
            app.EF_lbv_bovia_2.Value = [];
            app.EF_Ldamcau_ngoaikhoi_2 = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_Ldamcau_ngoaikhoi_2.AllowEmpty = 'on';
            app.EF_Ldamcau_ngoaikhoi_2.Editable = 'off';
            app.EF_Ldamcau_ngoaikhoi_2.Value = [];
            app.EF_Ldamcau_trongbo_2 = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_Ldamcau_trongbo_2.AllowEmpty = 'on';
            app.EF_Ldamcau_trongbo_2.Editable = 'off';
            app.EF_Ldamcau_trongbo_2.Value = [];
            app.EF_slg_vln = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_slg_vln.AllowEmpty = 'on';
            app.EF_slg_vln.Editable = 'off';
            app.EF_slg_vln.Value = [];
            app.EF_slg_bovia = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_slg_bovia.AllowEmpty = 'on';
            app.EF_slg_bovia.Editable = 'off';
            app.EF_slg_bovia.Value = [];
            app.EF_slg_vanvet = uieditfield(app.Panel_KetQuaMatCau, 'numeric');
            app.EF_slg_vanvet.AllowEmpty = 'on';
            app.EF_slg_vanvet.Editable = 'off';
            app.EF_slg_vanvet.Value = [];
            app.Lbl_Lvln_2 = uilabel(app.Panel_KetQuaMatCau);
            app.Lbl_Lvln_2.Text = 'Số lượng (cái)';
            app.Lbl_kt_bv_16 = uilabel(app.UIFigure);
            app.Lbl_kt_bv_16.Text = '2. Xà đế';
            app.EF_kt_xade = uieditfield(app.UIFigure, 'text');
            app.EF_kt_xade.Editable = 'off';
            app.EF_kt_xade.Visible = 'off';
            app.EF_chieudai_xade = uieditfield(app.UIFigure, 'numeric');
            app.EF_chieudai_xade.AllowEmpty = 'on';
            app.EF_chieudai_xade.Editable = 'off';
            app.EF_chieudai_xade.Value = [];
            app.EF_LeBoHanh = uieditfield(app.UIFigure, 'numeric');
            app.EF_LeBoHanh.RoundFractionalValues = 'on';
            app.EF_LeBoHanh.AllowEmpty = 'on';
            app.EF_LeBoHanh.Value = [];
            app.Lbl_LeBoHanh = uilabel(app.UIFigure);
            app.Lbl_LeBoHanh.Text = {'Chiều rộng lề bộ hành'; ''};
            app.EF_LanCan = uieditfield(app.UIFigure, 'numeric');
            app.EF_LanCan.RoundFractionalValues = 'on';
            app.EF_LanCan.AllowEmpty = 'on';
            app.EF_LanCan.Value = [];
            app.Lbl_LanCan = uilabel(app.UIFigure);
            app.Lbl_LanCan.Text = {'Chiều cao lan can'; ''};
            app.EF_SL_xade_changia = uieditfield(app.UIFigure, 'numeric');
            app.EF_SL_xade_changia.AllowEmpty = 'on';
            app.EF_SL_xade_changia.Editable = 'off';
            app.EF_SL_xade_changia.Value = [];
            app.Panel_KetQuaMoCau = uipanel(app.UIFigure);
            app.EF_kt_damo = uieditfield(app.Panel_KetQuaMoCau, 'text');
            app.EF_kt_damo.Editable = 'off';
            app.EF_chieudai_damo = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_chieudai_damo.AllowEmpty = 'on';
            app.EF_chieudai_damo.Editable = 'off';
            app.EF_chieudai_damo.Value = [];
            app.Lbl_kt_bv_28 = uilabel(app.Panel_KetQuaMoCau);
            app.Lbl_kt_bv_28.Text = '1. Đà mố';
            app.Lbl_kt_bv_29 = uilabel(app.Panel_KetQuaMoCau);
            app.Lbl_kt_bv_29.Text = 'Kích thước (cm)';
            app.Lbl_kt_bv_30 = uilabel(app.Panel_KetQuaMoCau);
            app.Lbl_kt_bv_30.Text = 'Chiều dài (cm)';
            app.Lbl_kt_bv_31 = uilabel(app.Panel_KetQuaMoCau);
            app.Lbl_kt_bv_31.Text = '2. Đà kê';
            app.EF_kt_dekemo = uieditfield(app.Panel_KetQuaMoCau, 'text');
            app.EF_kt_dekemo.Editable = 'off';
            app.EF_l_dakemo = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_l_dakemo.AllowEmpty = 'on';
            app.EF_l_dakemo.Editable = 'off';
            app.EF_l_dakemo.Value = [];
            app.Lbl_kt_bv_32 = uilabel(app.Panel_KetQuaMoCau);
            app.Lbl_kt_bv_32.Text = {'6. Cọc hướng '; 'dẫn xe'};
            app.EF_kt_cochuongdanxe = uieditfield(app.Panel_KetQuaMoCau, 'text');
            app.EF_kt_cochuongdanxe.Editable = 'off';
            app.EF_chieudai_cochuongdanxe = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_chieudai_cochuongdanxe.AllowEmpty = 'on';
            app.EF_chieudai_cochuongdanxe.Editable = 'off';
            app.EF_chieudai_cochuongdanxe.Value = [];
            app.Lbl_kt_bv_33 = uilabel(app.Panel_KetQuaMoCau);
            app.Lbl_kt_bv_33.Text = {'4. Cọc bịt '; 'đầu dầm'};
            app.EF_kt_cocbitdaudam = uieditfield(app.Panel_KetQuaMoCau, 'text');
            app.EF_kt_cocbitdaudam.Editable = 'off';
            app.EF_chieudai_cocbitdaudam = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_chieudai_cocbitdaudam.AllowEmpty = 'on';
            app.EF_chieudai_cocbitdaudam.Editable = 'off';
            app.EF_chieudai_cocbitdaudam.Value = [];
            app.Lbl_SL_changia_3 = uilabel(app.Panel_KetQuaMoCau);
            app.Lbl_SL_changia_3.Text = 'Số lượng (cái)';
            app.EF_SL_dake = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_SL_dake.AllowEmpty = 'on';
            app.EF_SL_dake.Editable = 'off';
            app.EF_SL_dake.Value = [];
            app.EF_SL_cochuongdanxe = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_SL_cochuongdanxe.AllowEmpty = 'on';
            app.EF_SL_cochuongdanxe.Editable = 'off';
            app.EF_SL_cochuongdanxe.Value = [];
            app.EF_SL_damo = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_SL_damo.AllowEmpty = 'on';
            app.EF_SL_damo.Editable = 'off';
            app.EF_SL_damo.Value = [];
            app.Lbl_kt_bv_34 = uilabel(app.Panel_KetQuaMoCau);
            app.Lbl_kt_bv_34.Text = '3. Cọc ghìm';
            app.EF_kt_cocghim = uieditfield(app.Panel_KetQuaMoCau, 'text');
            app.EF_kt_cocghim.Editable = 'off';
            app.EF_l_cocghim = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_l_cocghim.AllowEmpty = 'on';
            app.EF_l_cocghim.Editable = 'off';
            app.EF_l_cocghim.Value = [];
            app.EF_SL_cocghim = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_SL_cocghim.AllowEmpty = 'on';
            app.EF_SL_cocghim.Editable = 'off';
            app.EF_SL_cocghim.Value = [];
            app.EF_SL_cocbitdaudam = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_SL_cocbitdaudam.AllowEmpty = 'on';
            app.EF_SL_cocbitdaudam.Editable = 'off';
            app.EF_SL_cocbitdaudam.Value = [];
            app.Lbl_kt_bv_35 = uilabel(app.Panel_KetQuaMoCau);
            app.Lbl_kt_bv_35.Text = '5. Ván chắn đất';
            app.EF_kt_vanchandat = uieditfield(app.Panel_KetQuaMoCau, 'text');
            app.EF_kt_vanchandat.Editable = 'off';
            app.EF_chieudai_vanchandat = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_chieudai_vanchandat.AllowEmpty = 'on';
            app.EF_chieudai_vanchandat.Editable = 'off';
            app.EF_chieudai_vanchandat.Value = [];
            app.EF_SL_vanchandat = uieditfield(app.Panel_KetQuaMoCau, 'numeric');
            app.EF_SL_vanchandat.AllowEmpty = 'on';
            app.EF_SL_vanchandat.Editable = 'off';
            app.EF_SL_vanchandat.Value = [];
            app.Panel_KetQuaChanGia = uipanel(app.UIFigure);
            app.EF_kt_xadau = uieditfield(app.Panel_KetQuaChanGia, 'text');
            app.EF_kt_xadau.Editable = 'off';
            app.EF_chieudai_xadau = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_chieudai_xadau.AllowEmpty = 'on';
            app.EF_chieudai_xadau.Editable = 'off';
            app.EF_chieudai_xadau.Value = [];
            app.Lbl_kt_bv_12 = uilabel(app.Panel_KetQuaChanGia);
            app.Lbl_kt_bv_12.Text = {'1. Xà đầu,'; ' Xà đế'};
            app.Lbl_kt_bv_13 = uilabel(app.Panel_KetQuaChanGia);
            app.Lbl_kt_bv_13.Text = 'Kích thước (cm)';
            app.Lbl_kt_bv_14 = uilabel(app.Panel_KetQuaChanGia);
            app.Lbl_kt_bv_14.Text = 'Chiều dài (cm)';
            app.Lbl_kt_bv_17 = uilabel(app.Panel_KetQuaChanGia);
            app.Lbl_kt_bv_17.Text = '2. Cột giá';
            app.EF_kt_cotgia = uieditfield(app.Panel_KetQuaChanGia, 'text');
            app.EF_kt_cotgia.Editable = 'off';
            app.EF_l_cotgia = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_l_cotgia.AllowEmpty = 'on';
            app.EF_l_cotgia.Editable = 'off';
            app.EF_l_cotgia.Value = [];
            app.Lbl_kt_bv_18 = uilabel(app.Panel_KetQuaChanGia);
            app.Lbl_kt_bv_18.Text = '6. Đà kê';
            app.EF_kt_dake = uieditfield(app.Panel_KetQuaChanGia, 'text');
            app.EF_kt_dake.Editable = 'off';
            app.EF_chieudai_dake = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_chieudai_dake.AllowEmpty = 'on';
            app.EF_chieudai_dake.Editable = 'off';
            app.EF_chieudai_dake.Value = [];
            app.Lbl_kt_bv_19 = uilabel(app.Panel_KetQuaChanGia);
            app.Lbl_kt_bv_19.Text = {'4. Giằng chéo'; 'chân giá'};
            app.EF_kt_giangcheo = uieditfield(app.Panel_KetQuaChanGia, 'text');
            app.EF_kt_giangcheo.Editable = 'off';
            app.EF_chieudai_giangcheo = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_chieudai_giangcheo.AllowEmpty = 'on';
            app.EF_chieudai_giangcheo.Editable = 'off';
            app.EF_chieudai_giangcheo.Value = [];
            app.Lbl_SL_changia = uilabel(app.Panel_KetQuaChanGia);
            app.Lbl_SL_changia.Text = 'Số lượng (cái)';
            app.EF_SL_cotgia_changia = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_SL_cotgia_changia.AllowEmpty = 'on';
            app.EF_SL_cotgia_changia.Editable = 'off';
            app.EF_SL_cotgia_changia.Value = [];
            app.EF_SL_dake_changia = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_SL_dake_changia.AllowEmpty = 'on';
            app.EF_SL_dake_changia.Editable = 'off';
            app.EF_SL_dake_changia.Value = [];
            app.EF_SL_xadau_changia = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_SL_xadau_changia.AllowEmpty = 'on';
            app.EF_SL_xadau_changia.Editable = 'off';
            app.EF_SL_xadau_changia.Value = [];
            app.Lbl_kt_bv_26 = uilabel(app.Panel_KetQuaChanGia);
            app.Lbl_kt_bv_26.Text = '3. Cọc';
            app.EF_kt_coc = uieditfield(app.Panel_KetQuaChanGia, 'text');
            app.EF_kt_coc.Editable = 'off';
            app.EF_l_coc = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_l_coc.AllowEmpty = 'on';
            app.EF_l_coc.Editable = 'off';
            app.EF_l_coc.Value = [];
            app.EF_SL_coc = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_SL_coc.AllowEmpty = 'on';
            app.EF_SL_coc.Editable = 'off';
            app.EF_SL_coc.Value = [];
            app.EF_SL_giangcheo_changia = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_SL_giangcheo_changia.AllowEmpty = 'on';
            app.EF_SL_giangcheo_changia.Editable = 'off';
            app.EF_SL_giangcheo_changia.Value = [];
            app.Lbl_kt_bv_27 = uilabel(app.Panel_KetQuaChanGia);
            app.Lbl_kt_bv_27.Text = {'5. Giằng chéo'; 'chân cọc'};
            app.EF_kt_giangcheo_chancoc = uieditfield(app.Panel_KetQuaChanGia, 'text');
            app.EF_kt_giangcheo_chancoc.Editable = 'off';
            app.EF_chieudai_giangcheo_chancoc = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_chieudai_giangcheo_chancoc.AllowEmpty = 'on';
            app.EF_chieudai_giangcheo_chancoc.Editable = 'off';
            app.EF_chieudai_giangcheo_chancoc.Value = [];
            app.EF_SL_giangcheo_chancoc = uieditfield(app.Panel_KetQuaChanGia, 'numeric');
            app.EF_SL_giangcheo_chancoc.AllowEmpty = 'on';
            app.EF_SL_giangcheo_chancoc.Editable = 'off';
            app.EF_SL_giangcheo_chancoc.Value = [];
            app.BT_TinhMoCau = uibutton(app.UIFigure, 'push');
            app.BT_TinhMoCau.ButtonPushedFcn = createCallbackFcn(app, @BT_TinhMoCauButtonPushed, true);
            app.BT_TinhMoCau.Text = 'TÍNH MỐ CẦU';
            app.BT_TinhChanGia = uibutton(app.UIFigure, 'push');
            app.BT_TinhChanGia.ButtonPushedFcn = createCallbackFcn(app, @BT_TinhChanGiaButtonPushed, true);
            app.BT_TinhChanGia.Text = 'TÍNH CHÂN GIÁ';
            app.PanelInputChanGia = uipanel(app.UIFigure);
            app.Lbl_ChieuCaoChanCau = uilabel(app.PanelInputChanGia);
            app.Lbl_ChieuCaoChanCau.Text = '1. Chiều cao chân cầu H (m)';
            app.EF_H_chan = uieditfield(app.PanelInputChanGia, 'numeric');
            app.EF_H_chan.AllowEmpty = 'on';
            app.EF_H_chan.ValueChangedFcn = createCallbackFcn(app, @EF_H_chanValueChanged2, true);
            app.Lbl_DuongKinhCotGiaGiaThiet = uilabel(app.PanelInputChanGia);
            app.Lbl_DuongKinhCotGiaGiaThiet.Text = '3. Kích thước cột, cọc giả thiết';
            app.Lbl_DuongKinhXaDauGiaThiet = uilabel(app.PanelInputChanGia);
            app.Lbl_DuongKinhXaDauGiaThiet.Text = '6. Kích thước xà đầu giả thiết';
            app.Lbl_ChieuCaoChanCau_5 = uilabel(app.PanelInputChanGia);
            app.Lbl_ChieuCaoChanCau_5.Text = '2. Số cột giá trong một hàng';
            app.DD_m_cot = uidropdown(app.PanelInputChanGia);
            app.DD_m_cot.Items = {'4', '6'};
            app.DD_m_cot.Tooltip = {'X20 m = 4'; 'X40, X60 m = 4 hoặc 6'};
            app.DD_m_cot.Value = '4';
            app.DD_LoaiGoDaKe = uidropdown(app.PanelInputChanGia);
            app.DD_LoaiGoDaKe.Items = {'Tròn', 'Xẻ'};
            app.DD_LoaiGoDaKe.Tooltip = {'X20 m = 4'; 'X40, X60 m = 4 hoặc 6'};
            app.DD_LoaiGoDaKe.Value = 'Xẻ';
            app.Lbl_ChieuCaoChanCau_10 = uilabel(app.PanelInputChanGia);
            app.Lbl_ChieuCaoChanCau_10.Text = '4. Loại gỗ đà kê';
            app.Lbl_ChieuCaoChanCau_11 = uilabel(app.PanelInputChanGia);
            app.Lbl_ChieuCaoChanCau_11.Text = '5. Loại gỗ giằng chéo';
            app.DD_LoaiGoGiangCheo = uidropdown(app.PanelInputChanGia);
            app.DD_LoaiGoGiangCheo.Items = {'Tròn', 'Tròn xẻ đôi', 'Xẻ'};
            app.DD_LoaiGoGiangCheo.Tooltip = {'X20 m = 4'; 'X40, X60 m = 4 hoặc 6'};
            app.DD_LoaiGoGiangCheo.Value = 'Xẻ';
            app.DD_dCotGia = uidropdown(app.PanelInputChanGia);
            app.DD_dCotGia.Items = {'-', 'Option 2', 'Option 3', 'Option 4'};
            app.DD_dCotGia.ValueChangedFcn = createCallbackFcn(app, @DD_dCotGiaValueChanged, true);
            app.DD_dCotGia.Value = '-';
            app.DD_dXaDau = uidropdown(app.PanelInputChanGia);
            app.DD_dXaDau.Items = {'-', 'Option 2', 'Option 3', 'Option 4'};
            app.DD_dXaDau.ValueChangedFcn = createCallbackFcn(app, @DD_dXaDauValueChanged, true);
            app.DD_dXaDau.Value = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CauGo_AI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end